classdef GUI_comp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        tabgp                          matlab.ui.container.TabGroup
        img                            matlab.ui.container.Tab
        im0                            matlab.ui.control.Image
        im1                            matlab.ui.control.Image
        Path                           matlab.ui.control.Label
        RightImageLabel                matlab.ui.control.Label
        LeftImageLabel                 matlab.ui.control.Label
        FolderSelect                   matlab.ui.control.Button
        Compute                        matlab.ui.control.Button
        HarrisDetectionTab             matlab.ui.container.Tab
        RightImageLabel_2              matlab.ui.control.Label
        LeftImageLabel_2               matlab.ui.control.Label
        UIAxesHarris                   matlab.ui.control.UIAxes
        CorrespondenceTab              matlab.ui.container.Tab
        RightImageLabel_3              matlab.ui.control.Label
        LeftImageLabel_3               matlab.ui.control.Label
        UIAxesCorr                     matlab.ui.control.UIAxes
        RANSACTab                      matlab.ui.container.Tab
        RightImageLabel_4              matlab.ui.control.Label
        LeftImageLabel_4               matlab.ui.control.Label
        UIAxesRANSAC                   matlab.ui.control.UIAxes
        ReconstructionTab              matlab.ui.container.Tab
        Reconstruction_Plot            matlab.ui.control.UIAxes
        DisparityTab                   matlab.ui.container.Tab
        DisparityAccuracyListBoxLabel  matlab.ui.control.Label
        DisparityAccuracyListBox       matlab.ui.control.ListBox
        UIAxesDisparity                matlab.ui.control.UIAxes
        computation                    matlab.ui.control.Label
        DTab                           matlab.ui.container.Tab
        UIAxes3D                       matlab.ui.control.UIAxes
        RTpTab                         matlab.ui.container.Tab
        RTable                         matlab.ui.control.Table
        TTable                         matlab.ui.control.Table
        PLabel                         matlab.ui.control.Label
        GT                             matlab.ui.control.Image
        GTLab                          matlab.ui.control.Label
        RotationMatrixLabel            matlab.ui.control.Label
        TranslationVectorLabel         matlab.ui.control.Label
        ComputerVisionUltimateDisparityCalculatorLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property % Description
    end
    
    properties (Access = private)
        scene_path % Description
        cal;
        im0g;
        im1g;
        feature0;
        feature1;
        correspondence;
        E;
        T1;
        R1;
        T2;
        R2;
        T;
        R;
        lambda;
        dispMap;
        c1;
        c0;
        base;
        scale = [100 150];
        window = 3;
        disparity_max = 10;
        I_3d;
        p;
        
    end
    
    
    methods (Access = private)
        function computeEverything(app)
            
            app.cal = fileread([ app.scene_path '/calib.txt']);
            %% Create calibration variables
            evalc(app.cal);
            app.c0 = cam0;
            app.c1 = cam1;
            app.base = baseline;
            
            
            %% Load images and calibration file from scene folder
            im0_load = imread([app.scene_path '/im0.png']);
            im1_load = imread([app.scene_path '/im1.png']);
            if length(size(im0_load)) == 3
                app.im0g= rgb_to_gray(im0_load);
                app.im1g= rgb_to_gray(im1_load);
            else
                app.im0g = im0_load;
                app.im1g = im1_load;
            end
            
            runHarris(app);
            runCorrespondence(app);
            runRANSAC(app);
            runAchtpunkt(app);
            runTausR(app);
            runReconstruction(app);
            runDisparity(app);
            
            surf(app.UIAxes3D,app.I_3d);
            
            % Load the ground truth
            G = readpfm(fullfile(app.scene_path,'disp0.pfm'));
            G = (G - min(min(G))) ./ max(max((G - min(min(G))))) * 255;
            
            % Compute the PSNR
            app.p = verify_dmap(app.dispMap, G);
                        
            % Show rotation matrix
            app.RTable.ColumnName = [];
            data = table(app.R);
            app.RTable.Data = data;
            app.RTable.ColumnWidth = 'auto';
            app.RTable.Visible = true;
            
            % Show translation matrix
            app.TTable.ColumnName = [];
            data = table(app.T);
            app.TTable.Data = data;
            app.TTable.ColumnWidth = 'auto';
            app.TTable.Visible = true;
            
            % Show p label
            app.PLabel.Text = sprintf('p = %f dB', app.p);
            app.PLabel.Visible = true;
            
        end
        
        function runHarris(app)
            %% Feature extraction
            har_det = @(img) harris_detektor(img,'segment_length',9,'k',0.05, ...
                'min_dist',30,'N',20);
            
            app.feature0 = [har_det(highpassFilt(app,app.im0g)), har_det(app.im0g)];
            app.feature1 = [har_det(highpassFilt(app,app.im1g)), har_det(app.im1g)];
            
            imshow(uint8([app.im0g,app.im1g]),'Parent',app.UIAxesHarris);
            hold(app.UIAxesHarris,'on');
            plot(app.UIAxesHarris, app.feature0(1,:),app.feature0(2,:),'g+');
            plot(app.UIAxesHarris, app.feature1(1,:) + size(app.im0g,2),app.feature1(2,:),'g+');
            hold(app.UIAxesHarris,'off');
        end
        
        function runCorrespondence( app)
            app.correspondence = punkt_korrespondenzen_jo(app.im0g,app.im1g,app.feature0,app.feature1,'min_corr', 0.99, 'do_plot', false);
            
            imshow(uint8([app.im0g,app.im1g]),'Parent',app.UIAxesCorr);
            hold(app.UIAxesCorr,'on');
            plot(app.UIAxesCorr,[app.correspondence(1,:),app.correspondence(3,:)+size(app.im0g,2)],...
                [app.correspondence(2,:),app.correspondence(4,:)],'go');
            % plot the line
            for i=1:size(app.correspondence,2)
                pt1 = [app.correspondence(1,i), app.correspondence(3,i)+size(app.im0g,2)];
                pt2 = [app.correspondence(2,i),app.correspondence(4,i)];
                line(app.UIAxesCorr,pt1,pt2);
            end
            hold(app.UIAxesCorr,'off');
        end
        
        function runRANSAC(app)
            %% Correspondence estimation - Find Robust Match
            %  Find robust correspondence point pairs with RANSAC-algorithm
            app.correspondence = F_ransac(app.correspondence, 'tolerance', 0.04);
            
            imshow(uint8([app.im0g,app.im1g]),'Parent',app.UIAxesRANSAC);
            hold(app.UIAxesRANSAC,'on');
            plot(app.UIAxesRANSAC,[app.correspondence(1,:),app.correspondence(3,:)+size(app.im0g,2)],...
                [app.correspondence(2,:),app.correspondence(4,:)],'go');
            % plot the line
            for i=1:size(app.correspondence,2)
                pt1 = [app.correspondence(1,i), app.correspondence(3,i)+size(app.im0g,2)];
                pt2 = [app.correspondence(2,i),app.correspondence(4,i)];
                line(app.UIAxesRANSAC,pt1,pt2);
            end
            hold(app.UIAxesRANSAC,'off');
        end
        
        function runAchtpunkt(app)
            %% Calculate essential matrix E
            app.E = achtpunktalgorithmus(app.correspondence,app.c0, app.c1);
        end
        
        function runTausR(app)
            %% Calculate possible T and R
            [app.T1, app.R1, app.T2, app.R2, ~, ~] = TR_aus_E(app.E);
        end
        
        function runReconstruction(app)
            %% Calculate correct euklidian transformation and 3D reconstruction
            [app.T, app.R,app.lambda,~,~] = rekonstruktion(app.T1, app.T2, app.R1, app.R2, app.correspondence, ...
                app.c0, app.c1, false);
            
            x1 = app.c0 \ [app.correspondence(1:2,:); ones(1, size(app.correspondence,2))];
            P1 = bsxfun(@times, app.lambda(:,1)', x1);
            hold(app.Reconstruction_Plot,'on');
            for i = 1:size(P1,2)
                scatter3(app.Reconstruction_Plot,P1(1,i), P1(2,i), P1(3,i), '.k');
                text(app.Reconstruction_Plot,P1(1,i), P1(2,i), P1(3,i),num2str(i));
            end
            
            % Store edge values
            camC1 = [-0.2,0.2,0.2,-0.2;0.2,0.2,-0.2,-0.2;1,1,1,1];
            
            % Calculate values for C2 via euklidian transformations...
            camC2 = app.R \ (bsxfun(@plus, camC1, -app.T));
            
            % Calculate camera plane positions (closing the plane)
            cam1_plane = camC1(:,[1:4 1]);
            cam2_plane = camC2(:,[1:4 1]);
            
            % Plot camera plane
            plot3(app.Reconstruction_Plot,cam1_plane(1,:), cam1_plane(2,:), cam1_plane(3,:), 'b');
            text(app.Reconstruction_Plot,cam1_plane(1,4), cam1_plane(2,4), cam1_plane(3,4), 'C1');
            plot3(app.Reconstruction_Plot,cam2_plane(1,:), cam2_plane(2,:), cam2_plane(3,:), 'r');
            text(app.Reconstruction_Plot,cam2_plane(1,4), cam2_plane(2,4), cam2_plane(3,4), 'C2');
            
            % Set camera position
            campos(app.Reconstruction_Plot,[43, -22, -87]);
            camup(app.Reconstruction_Plot,[0,-1,0]);
            
            % Add grid and axis label
            xlabel(app.Reconstruction_Plot,'x'); ylabel(app.Reconstruction_Plot,'y'); zlabel(app.Reconstruction_Plot,'z');
            grid on;
            
            hold(app.Reconstruction_Plot,'off');
        end
        
        function runDisparity(app)
            app.computation.Text = "Computing...";
            %% Resize Images
            %% Shrink images
            if prod(app.scale) < numel(app.im0g)
                im0g_scaled = interpolateImage(app.im0g, app.scale);
                im1g_scaled = interpolateImage(app.im1g, app.scale);
            else
                im0g_scaled = app.im0g;
                im1g_scaled = app.im1g;
            end
            
            %% Calculate Disparity Map
            D_ = denseMatch(im0g_scaled, im1g_scaled,  app.window, 0, app.disparity_max, 'ZNCC');
            D = interpolateImage(D_,size(app.im0g));
            
            %% Depth Calculation
            app.I_3d = app.c1(1,1) * app.base ./ D; % baseline = norm(T,2)???
            
            %%Scale
            app.dispMap = min(D,255);
            
            imshow(app.dispMap,[0 app.disparity_max],'Parent',app.UIAxesDisparity);
            colormap(app.UIAxesDisparity,jet);
            colorbar(app.UIAxesDisparity);
            
            app.computation.Text = "Finished!";
        end
        
        
        function img_fil = highpassFilt(~,img)
            %% High pass filter
            img_fft = fftshift(fft2(img));
            filter_percentage = 0.01;
            img_fft_fil = img_fft;
            img_fft_fil(floor(end/2-end*filter_percentage):ceil(end/2+end*filter_percentage),...
                floor(end/2-end*filter_percentage):ceil(end/2+end*filter_percentage)) = 0;
            img_fil = uint8(real(ifft2(fftshift(img_fft_fil))));
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('lib'));
            app.DisparityAccuracyListBox.Multiselect = 'off';
            app.Path.Visible = false;
            
            % Um den Ordnerpfad zu erhalten, wird ein User Interface
            % geöffnet mit dem man zum gewünschten Ordner navigieren kann
            app.scene_path = uigetdir(pwd,"Please select the folder with your test images");
            % Die Bilder werden, so im Ordner vorhanden, geladen und
            % angezeigt. Zudem wird der Ordnerpfad angezeigt.
            if exist(fullfile(app.scene_path, 'Im0.png'), "file") && ...
                    exist(fullfile(app.scene_path, 'Im1.png'), "file") &&...
                    exist(fullfile(app.scene_path, 'disp0.pfm'), "file")
                app.im0.ImageSource = fullfile(app.scene_path, 'Im0.png');
                app.im1.ImageSource = fullfile(app.scene_path, 'Im1.png');
                app.Path.Text = ["Path: ..." app.scene_path(end/2:end)];
            else
                app.Path.Text = 'Please select a scene folder with two images and a ground truth.';
                app.Path.Visible = true;
            end
        end

        % Button pushed function: FolderSelect
        function FolderSelectButtonPushed(app, event)
            % Um den Ordnerpfad zu erhalten, wird ein User Interface
            % geöffnet mit dem man zum gewünschten Ordner navigieren kann
            app.scene_path = uigetdir(pwd,"Please select the folder with your test images");
            % Die Bilder werden, so im Ordner vorhanden, geladen und
            % angezeigt. Zudem wird der Ordnerpfad angezeigt.
            if exist(fullfile(app.scene_path, 'Im0.png'), "file") && ...
                    exist(fullfile(app.scene_path, 'Im1.png'), "file") &&...
                    exist(fullfile(app.scene_path, 'disp0.pfm'), "file")
                app.im0.ImageSource = fullfile(app.scene_path, 'Im0.png');
                app.im1.ImageSource = fullfile(app.scene_path, 'Im1.png');
                app.Path.Text = ["Path: ..." app.scene_path(end/2:end)];
            else
                app.Path.Text = 'Please select a scene folder with two images and a ground truth.';
                app.Path.Visible = true;
            end
        end

        % Button pushed function: Compute
        function ComputeButtonPushed(app, event)
            computeEverything(app);
        end

        % Value changed function: DisparityAccuracyListBox
        function DisparityAccuracyListBoxValueChanged(app, event)
            value = app.DisparityAccuracyListBox.Value;
            
            switch value
                case 'Ultra Low'
                    %% Dummy Params
                    app.scale = [100 150]; app.window = 3; app.disparity_max = 10;% < 30s
                case 'Low'
                    %% Fast Params
                    app.scale = [217 356]; app.window = 5; app.disparity_max = 30;% < 30s
                    
                case 'Medium'
                    %% Medium Dense Params
                    app.scale = [434,713]; app.window = 15; app.disparity_max = 75;% < 5 min
                    
                case 'High'
                    %% Very Dense Params
                    app.scale =[868, 1426] ; app.window = 35; app.disparity_max = 100;% ~ 2 h
                    
                case 'Full'
                    %% Ultra Dense Params
                    app.scale = 1; app.window = 49; app.disparity_max = 200;% ~ inf d
            end
            app.DisparityAccuracyListBox.Enable = 'off';
            runDisparity(app);
            app.DisparityAccuracyListBox.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.tabgp = uitabgroup(app.UIFigure);
            app.img = uitab(app.tabgp);
            app.img.Title = 'Original';
            app.im0 = uiimage(app.img);
            app.im1 = uiimage(app.img);
            app.Path = uilabel(app.img);
            app.RightImageLabel = uilabel(app.img);
            app.RightImageLabel.Text = 'Right Image';
            app.LeftImageLabel = uilabel(app.img);
            app.LeftImageLabel.Text = 'Left Image';
            app.FolderSelect = uibutton(app.img, 'push');
            app.FolderSelect.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelect.Text = 'New Folder';
            app.Compute = uibutton(app.img, 'push');
            app.Compute.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.Compute.Text = 'Start Computation';
            app.HarrisDetectionTab = uitab(app.tabgp);
            app.HarrisDetectionTab.Title = 'Harris Detection';
            app.RightImageLabel_2 = uilabel(app.HarrisDetectionTab);
            app.RightImageLabel_2.Text = 'Right Image';
            app.LeftImageLabel_2 = uilabel(app.HarrisDetectionTab);
            app.LeftImageLabel_2.Text = 'Left Image';
            app.UIAxesHarris = uiaxes(app.HarrisDetectionTab);
            title(app.UIAxesHarris, 'Title')
            xlabel(app.UIAxesHarris, 'X')
            ylabel(app.UIAxesHarris, 'Y')
            app.CorrespondenceTab = uitab(app.tabgp);
            app.CorrespondenceTab.Title = 'Correspondence';
            app.RightImageLabel_3 = uilabel(app.CorrespondenceTab);
            app.RightImageLabel_3.Text = 'Right Image';
            app.LeftImageLabel_3 = uilabel(app.CorrespondenceTab);
            app.LeftImageLabel_3.Text = 'Left Image';
            app.UIAxesCorr = uiaxes(app.CorrespondenceTab);
            title(app.UIAxesCorr, 'Title')
            xlabel(app.UIAxesCorr, 'X')
            ylabel(app.UIAxesCorr, 'Y')
            app.RANSACTab = uitab(app.tabgp);
            app.RANSACTab.Title = 'RANSAC';
            app.RightImageLabel_4 = uilabel(app.RANSACTab);
            app.RightImageLabel_4.Text = 'Right Image';
            app.LeftImageLabel_4 = uilabel(app.RANSACTab);
            app.LeftImageLabel_4.Text = 'Left Image';
            app.UIAxesRANSAC = uiaxes(app.RANSACTab);
            title(app.UIAxesRANSAC, 'Title')
            xlabel(app.UIAxesRANSAC, 'X')
            ylabel(app.UIAxesRANSAC, 'Y')
            app.ReconstructionTab = uitab(app.tabgp);
            app.ReconstructionTab.Title = 'Reconstruction';
            app.Reconstruction_Plot = uiaxes(app.ReconstructionTab);
            title(app.Reconstruction_Plot, '')
            xlabel(app.Reconstruction_Plot, 'x')
            ylabel(app.Reconstruction_Plot, 'Y')
            app.DisparityTab = uitab(app.tabgp);
            app.DisparityTab.Title = 'Disparity';
            app.DisparityAccuracyListBoxLabel = uilabel(app.DisparityTab);
            app.DisparityAccuracyListBoxLabel.Text = 'Disparity Accuracy';
            app.DisparityAccuracyListBox = uilistbox(app.DisparityTab);
            app.DisparityAccuracyListBox.Items = {'Ultra Low', 'Low', 'Medium', 'High', 'Full'};
            app.DisparityAccuracyListBox.ValueChangedFcn = createCallbackFcn(app, @DisparityAccuracyListBoxValueChanged, true);
            app.DisparityAccuracyListBox.Value = 'Ultra Low';
            app.UIAxesDisparity = uiaxes(app.DisparityTab);
            title(app.UIAxesDisparity, 'Title')
            xlabel(app.UIAxesDisparity, 'X')
            ylabel(app.UIAxesDisparity, 'Y')
            app.computation = uilabel(app.DisparityTab);
            app.computation.Text = 'Computing....';
            app.DTab = uitab(app.tabgp);
            app.DTab.Title = '3D';
            app.UIAxes3D = uiaxes(app.DTab);
            title(app.UIAxes3D, 'Title')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            app.RTpTab = uitab(app.tabgp);
            app.RTpTab.Title = 'R,T,p';
            app.RTable = uitable(app.RTpTab);
            app.RTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'};
            app.RTable.RowName = {};
            app.TTable = uitable(app.RTpTab);
            app.TTable.ColumnName = {'Column 1'};
            app.TTable.RowName = {};
            app.PLabel = uilabel(app.RTpTab);
            app.PLabel.Text = 'Label2';
            app.GT = uiimage(app.RTpTab);
            app.GTLab = uilabel(app.RTpTab);
            app.GTLab.Text = 'Ground Truth';
            app.RotationMatrixLabel = uilabel(app.RTpTab);
            app.RotationMatrixLabel.Text = 'Rotation Matrix';
            app.TranslationVectorLabel = uilabel(app.RTpTab);
            app.TranslationVectorLabel.Text = 'Translation Vector';
            app.ComputerVisionUltimateDisparityCalculatorLabel = uilabel(app.UIFigure);
            app.ComputerVisionUltimateDisparityCalculatorLabel.Text = 'Computer Vision - Ultimate Disparity Calculator';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_comp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end